classdef Set_Screen_Configuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScreenSetupUIFigure            matlab.ui.Figure
        HumanScreenDropDownLabel       matlab.ui.control.Label
        HumanScreenDropDown            matlab.ui.control.DropDown
        LeftMouseScreenDropDownLabel   matlab.ui.control.Label
        LeftMouseScreenDropDown        matlab.ui.control.DropDown
        RightMouseScreenDropDownLabel  matlab.ui.control.Label
        RightMouseScreenDropDown       matlab.ui.control.DropDown
        DisplayScreensButton           matlab.ui.control.Button
        SetScreensButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        screen_1_image
        screen_2_image
        screen_3_image
              
        screen_1_figure
        screen_2_figure
        screen_3_figure
        
        displaying_screens = false
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.screen_1_image = imread('C:\Users\Behaviour Only A\Pictures\Screen_Displays\Screen_1.png')
            app.screen_2_image = imread('C:\Users\Behaviour Only A\Pictures\Screen_Displays\Screen_2.png')
            app.screen_3_image = imread('C:\Users\Behaviour Only A\Pictures\Screen_Displays\Screen_3.png') 
            
            
        end

        % Button pushed function: DisplayScreensButton
        function DisplayScreensButtonPushed(app, event)
            
            
            if app.displaying_screens == false 
            
                %Get Monitor Positions
                monitor_positions = get(0, 'MonitorPositions')
                
                screen_1_pos = monitor_positions(1,1)
                screen_2_pos = monitor_positions(2,1)
                screen_3_pos = monitor_positions(3,1)
                
                screen_1_height = monitor_positions(1,3) 
                screen_2_height = monitor_positions(2,3) 
                screen_3_height = monitor_positions(3,3) 
                
                screen_1_width = monitor_positions(1,4)
                screen_2_width = monitor_positions(2,4)
                screen_3_width = monitor_positions(3,4)
                
                screen_1_figure_x = screen_1_pos + (screen_1_width*0.5) 
                screen_2_figure_x = screen_2_pos + (screen_2_width*0.5) 
                screen_3_figure_x = screen_3_pos + (screen_3_width*0.5) 
                
                screen_1_figure_y = screen_1_height*0.05
                screen_2_figure_y = screen_2_height*0.05 
                screen_3_figure_y = screen_3_height*0.05
                
                %Display A Figure On Each Screen Saying What It Is
                %screen_1_figure = figure('Position',[screen_1_figure_x screen_1_figure_y 500 500])
                %screen_2_figure = figure('Position',[screen_2_figure_x screen_2_figure_y 500 500])
                %screen_3_figure = figure('Position',[screen_3_figure_x screen_3_figure_y 500 500])
                
                app.screen_1_figure = figure()
                app.screen_2_figure = figure()
                app.screen_3_figure = figure()
    
                screen_1_axis = axes('Parent', app.screen_1_figure)
                screen_2_axis = axes('Parent', app.screen_2_figure)
                screen_3_axis = axes('Parent', app.screen_3_figure)
                
                imshow(app.screen_1_image, 'Parent', screen_1_axis)
                imshow(app.screen_2_image, 'Parent', screen_2_axis)
                imshow(app.screen_3_image, 'Parent', screen_3_axis)
                
                movegui(app.screen_1_figure,[screen_1_figure_x screen_1_figure_y]);
                movegui(app.screen_2_figure,[screen_2_figure_x screen_2_figure_y]);
                movegui(app.screen_3_figure,[screen_3_figure_x screen_3_figure_y]);
                
                app.displaying_screens = true
                app.DisplayScreensButton.Text = 'Close Screens'
                
            else
                delete(app.screen_1_figure)
                delete(app.screen_2_figure)
                delete(app.screen_3_figure)
                app.displaying_screens = false
                app.DisplayScreensButton.Text = 'Display Screens'


            end


        end

        % Close request function: ScreenSetupUIFigure
        function ScreenSetupUIFigureCloseRequest(app, event)
            
            try
                delete(app.screen_1_figure)
                delete(app.screen_2_figure)
                delete(app.screen_3_figure)
            catch 
                pass
                
            end
            
            delete(app)
            
        end

        % Button pushed function: SetScreensButton
        function SetScreensButtonPushed(app, event)
            
            %Get Dropdown Menu Values
            human_screen        = app.HumanScreenDropDown.Value
            left_mouse_screen   = app.LeftMouseScreenDropDown.Value
            right_mouse_screen  = app.RightMouseScreenDropDown.Value
            
            %Convert Strings To Numbers
            human_screen = str2num(human_screen)
            left_mouse_screen = str2num(left_mouse_screen)
            right_mouse_screen = str2num(right_mouse_screen)
            
            %Save Screen Configuration
            screen_configuration = {human_screen, left_mouse_screen, right_mouse_screen}
            save('C:\FSM_Settings\Screen_Configuration', "screen_configuration")
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScreenSetupUIFigure = uifigure('Visible', 'off');
            app.ScreenSetupUIFigure.Name = 'Screen Setup';
            app.ScreenSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @ScreenSetupUIFigureCloseRequest, true);
            app.HumanScreenDropDownLabel = uilabel(app.ScreenSetupUIFigure);
            app.HumanScreenDropDownLabel.Text = 'Human Screen';
            app.HumanScreenDropDown = uidropdown(app.ScreenSetupUIFigure);
            app.HumanScreenDropDown.Items = {'1', '2', '3'};
            app.HumanScreenDropDown.Value = '1';
            app.LeftMouseScreenDropDownLabel = uilabel(app.ScreenSetupUIFigure);
            app.LeftMouseScreenDropDownLabel.Text = 'Left Mouse Screen';
            app.LeftMouseScreenDropDown = uidropdown(app.ScreenSetupUIFigure);
            app.LeftMouseScreenDropDown.Items = {'1', '2', '3', ''};
            app.LeftMouseScreenDropDown.Value = '2';
            app.RightMouseScreenDropDownLabel = uilabel(app.ScreenSetupUIFigure);
            app.RightMouseScreenDropDownLabel.Text = 'Right Mouse Screen';
            app.RightMouseScreenDropDown = uidropdown(app.ScreenSetupUIFigure);
            app.RightMouseScreenDropDown.Items = {'1', '2', '3', ''};
            app.RightMouseScreenDropDown.Value = '3';
            app.DisplayScreensButton = uibutton(app.ScreenSetupUIFigure, 'push');
            app.DisplayScreensButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayScreensButtonPushed, true);
            app.DisplayScreensButton.Text = 'Display Screens';
            app.SetScreensButton = uibutton(app.ScreenSetupUIFigure, 'push');
            app.SetScreensButton.ButtonPushedFcn = createCallbackFcn(app, @SetScreensButtonPushed, true);
            app.SetScreensButton.Text = 'Set Screens';
            % Show the figure after all components are created
            app.ScreenSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Set_Screen_Configuration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScreenSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScreenSetupUIFigure)
        end
    end
end