classdef Eye_Cam_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DisplayAxes                 matlab.ui.control.UIAxes
        ConnectToCameraButton       matlab.ui.control.Button
        TeensyCOMPortDropDownLabel  matlab.ui.control.Label
        TeensyCOMPortDropDown       matlab.ui.control.DropDown
        ConnectToTeensyButton       matlab.ui.control.Button
        SetMouseNameButton          matlab.ui.control.Button
        PreviewButton               matlab.ui.control.Button
        RecordButton                matlab.ui.control.Button
        TeensyNotConnectedLabel     matlab.ui.control.Label
        MouseNameEditFieldLabel     matlab.ui.control.Label
        MouseNameEditField          matlab.ui.control.EditField
        CameraNotConnectedLabel     matlab.ui.control.Label
        MouseNameLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        video_input_object
        video_source
        connected_to_camera = false
        connected_to_teensy = false
        mouse_name = 'El Mause'
        teensy
        recording_state = 1
        save_directory = 'C:\Eye_Cam_Recordings\'
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectToCameraButton
        function ConnectToCameraButtonPushed(app, event)
            
            try 
                app.video_input_object = videoinput('tisimaq_r2013_64', 1, 'Y800 (320x240)');       %Create Video Input Object
                app.video_source = getselectedsource(app.video_input_object);                       %Connect This Object To The Camera
                
                stop(app.video_input_object);
                stoppreview(app.video_input_object);
                
                app.video_source.Gain = 63;                                                     %Set Camera to Have Gain of 63 (max)
                app.video_source.Exposure = 0.01;  
                
                app.CameraNotConnectedLabel.Text = "Connected To Camera :)";
                app.connected_to_camera = true
                
            catch 
                app.CameraNotConnectedLabel.Text = "Unable To Connect To Camera :(";
            
            end
        end

        % Button pushed function: ConnectToTeensyButton
        function ConnectToTeensyButtonPushed(app, event)
            
        %Connect To Teensy
        try
            com_port = app.TeensyCOMPortDropDown.Value
            app.teensy = serial(com_port)
            fopen(app.teensy)
            app.TeensyNotConnectedLabel.Text =  "Connected To Teensy :)";
            app.connected_to_teensy = true
            
        catch
            app.TeensyNotConnectedLabel.Text = "Unable To Connect To Teensy";
            app.connected_to_teensy = false
        end
           
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            %preview(app.video_input_object)
            
            if app.connected_to_camera == false
                warndlg('Camera Not Connected')
            else
                if app.recording_state == 1
                    preview_image = image(app.DisplayAxes, zeros([240,320], 'uint8'))
                    axis(app.DisplayAxes, 'image')
                    app.PreviewButton.Text = 'Stop Previewing'
                    app.recording_state = 2
                    app.RecordButton.Enable = false
                    preview(app.video_input_object, preview_image);
                    
                elseif app.recording_state == 2
               
                    stoppreview(app.video_input_object);
                    app.recording_state = 1
                    app.PreviewButton.Text = 'Preview'
                    app.RecordButton.Enable = true
                    
                end
            end
            
        end

        % Button pushed function: SetMouseNameButton
        function SetMouseNameButtonPushed(app, event)
            proposed_name = app.MouseNameEditField.Value
            if strlength(proposed_name) < 1
                warndlg('Please Name Your Mouse')
            else
                app.mouse_name = proposed_name
                app.MouseNameLabel.Text = app.mouse_name
            end
            
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            
            %Check Are We Already Recording Or Not?
            if app.recording_state == 3
                
        
                %fprintf(app.teensy, "4/n");                             %Send The Stop Signal To The Teensy
                
               
                %Sweep Up Remaning Frames
                % { We Should Give The Teensy Some time to read our stop instruction and stop sending triggers before we stop recording. 
                % Otherwise we may stop recording frames before the teensy stops sending triggers, then our number of frames and triggers wont match! %}
   

                %message = fscanf(app.teensy,'%s\n')
                
        
                %if message == 'Thatsthelastframecaptin!'
                    %stoppreview(app.video_input_object);                %Stop Previewing The Video
                    %stop(app.video_input_object);                       %Stop The Recording
                    %app.video_input_object.LoggingMode = 'memory';      %Tell The Camera To Stop Saving To Disk
                    %stoppreview(app.video_input_object);
                %end


                stop(app.video_input_object);                       %Stop The Recording
                app.video_input_object.LoggingMode = 'memory';      %Tell The Camera To Stop Saving To Disk
                stoppreview(app.video_input_object);
                
                
                % Renable The Other Buttons
                app.PreviewButton.Enable            = true;
                app.SetMouseNameButton.Enable       = true;
                app.ConnectToCameraButton.Enable    = true;
                app.ConnectToTeensyButton.Enable    = true;
                
                % Change The Recording State
                app.recording_state = 1                             %Change The Recording State To 1, waiting for input
                app.RecordButton.Text = 'Record'                    %Change The Recording Button Text Bacl


            else
                %Check If We Are Connected To The Camera
                if app.connected_to_camera == false
                    warndlg('Camera Not Connected')
                else
                    
                    %Check If We Are Connected To The Teensy
                    if app.connected_to_teensy == false
                        warndlg('Teensy Not Connected')
                        
                    else
                       
                        % Great We're Ready to Go!
                        
                        % Disable THe Other Buttons otherwise it will be chaos
                        app.PreviewButton.Enable            = false;
                        app.SetMouseNameButton.Enable       = false;
                        app.ConnectToCameraButton.Enable    = false;
                        app.ConnectToTeensyButton.Enable    = false;
                           
                        % Create File Name
                        timestamp = datestr(now,'yyyy-mm-dd-HH-MM-SS');                                         %Get Current Time
                        full_file_path = strcat(app.save_directory, app.mouse_name, "_", timestamp, ".avi")     %Create Full Filename Of Video
                        
                        %Setup Saving On The Camera
                        app.video_input_object.LoggingMode = 'disk';                                            %Tell The Camera To Save To Disk                                
                        diskLogger = VideoWriter(full_file_path, 'Grayscale AVI');                              %Create A "Disk Logger" This is how the image acquistion toolbox saves files
                        app.video_input_object.DiskLogger = diskLogger;                                         %Tell the camera to use the disk logger we just made
                    
                        %Tell Camera To Display On The GUI
                        preview_image = image(app.DisplayAxes, zeros([240,320], 'uint8'))                       %Create An Image object The same size as our video (we need to do this to view the video, classic matlab)
                        axis(app.DisplayAxes, 'image')                                                          %Tell our axes to display this image (you can only view video on a graph in matlab.. I know)
       
                        
                        %Setup Hardware Triggering
                        %app.video_source.Trigger = 'Enable';
                        triggerconfig(app.video_input_object, 'hardware', 'hardware', 'hardware');          %Set Camera To Use Hardware Trigger
                        app.video_input_object.FramesPerTrigger = 1;     
                        app.video_input_object.TriggerRepeat = Inf;                                          %Tell Camera To Capture 1 Frame Per Trigger
                        app.video_source.Exposure = 0.01;
                        app.video_source.FrameRate = '160.00';
                        
                        %Start Recoding
                        preview(app.video_input_object, preview_image);                                         %If you want to see the video as you record you also need to preview it, classic matlab
                        start(app.video_input_object);                                                          %Start Recording
                    
                        %Send Start Trigger To Teensy
                        flushoutput(app.teensy)
                        fprintf(app.teensy, "2/n");                                                                 %Send The Number 2 To The Teesy, this is the signal to start strobing
                        flushoutput(app.teensy)
                        
                        app.recording_state = 3                                                                 %Set the recording state to 3, so the app knows we are currently recording
                        app.RecordButton.Text = 'Stop Recording'                                                %Change the text on the recording button
                        
                    end
               end
                
           end
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

        try    
            fclose(app.teensy)
        catch
        end
        
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DisplayAxes = uiaxes(app.UIFigure);
            title(app.DisplayAxes, '')
            xlabel(app.DisplayAxes, '')
            ylabel(app.DisplayAxes, '')
            app.DisplayAxes.XTick = [];
            app.DisplayAxes.YTick = [];
            app.ConnectToCameraButton = uibutton(app.UIFigure, 'push');
            app.ConnectToCameraButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToCameraButtonPushed, true);
            app.ConnectToCameraButton.Text = 'Connect To Camera';
            app.TeensyCOMPortDropDownLabel = uilabel(app.UIFigure);
            app.TeensyCOMPortDropDownLabel.Text = 'Teensy COM Port';
            app.TeensyCOMPortDropDown = uidropdown(app.UIFigure);
            app.TeensyCOMPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.TeensyCOMPortDropDown.Value = 'COM1';
            app.ConnectToTeensyButton = uibutton(app.UIFigure, 'push');
            app.ConnectToTeensyButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToTeensyButtonPushed, true);
            app.ConnectToTeensyButton.Text = 'Connect To Teensy';
            app.SetMouseNameButton = uibutton(app.UIFigure, 'push');
            app.SetMouseNameButton.ButtonPushedFcn = createCallbackFcn(app, @SetMouseNameButtonPushed, true);
            app.SetMouseNameButton.Text = 'Set Mouse Name';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.TeensyNotConnectedLabel = uilabel(app.UIFigure);
            app.TeensyNotConnectedLabel.Text = 'Not Connected';
            app.MouseNameEditFieldLabel = uilabel(app.UIFigure);
            app.MouseNameEditFieldLabel.Text = 'Mouse Name';
            app.MouseNameEditField = uieditfield(app.UIFigure, 'text');
            app.CameraNotConnectedLabel = uilabel(app.UIFigure);
            app.CameraNotConnectedLabel.Text = 'Not Connected';
            app.MouseNameLabel = uilabel(app.UIFigure);
            app.MouseNameLabel.Text = 'El Mause';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Eye_Cam_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end